classdef Crosscorrelation_PS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        ToggleTool               matlab.ui.container.toolbar.ToggleTool
        ToggleTool2              matlab.ui.container.toolbar.ToggleTool
        GridLayout               matlab.ui.container.GridLayout
        WindowIdxLabel           matlab.ui.control.Label
        RePlotButton             matlab.ui.control.Button
        ModSigSizeLabel          matlab.ui.control.Label
        SampleSizeValueLabel     matlab.ui.control.Label
        SampleRateValueLabel     matlab.ui.control.Label
        SampleSizeLabel          matlab.ui.control.Label
        SampleRateLabel          matlab.ui.control.Label
        ButtonGroup              matlab.ui.container.ButtonGroup
        Ch3Button                matlab.ui.control.RadioButton
        Ch2Button                matlab.ui.control.RadioButton
        Ch1Button                matlab.ui.control.RadioButton
        StartButton              matlab.ui.control.Button
        LossdBSpinner            matlab.ui.control.Spinner
        LossdBSpinnerLabel       matlab.ui.control.Label
        NoisedBSpinner           matlab.ui.control.Spinner
        NoisedBSpinnerLabel      matlab.ui.control.Label
        SampleWindowSlider       matlab.ui.control.RangeSlider
        SampleWindowSliderLabel  matlab.ui.control.Label
        DelayUnitSpinner         matlab.ui.control.Spinner
        DelayUnitSpinnerLabel    matlab.ui.control.Label
        BaseSignalListBox        matlab.ui.control.ListBox
        BaseSignalLabel          matlab.ui.control.Label
        CrossCorrelatorLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Sig % Signal Data from wav file
        Fs % Sample Rate from wav file
        Mod_Sig % modified signal
        Sig_rows % signal rows
        Sig_cols % signal columns
        Sig_window % Windowed signal
        Mod_Sig_window % Window Modified signal
        LowerWindowLimit; % signal window limits
        UpperWindowLimit;
        % LWLidx; % Possible solution for the bug, that cannot plot only a windowsize between values and not indexes
        % UWLidx; 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_signal(app,[]); % just initialization at start up
            app.SampleWindowSlider.MajorTickLabels = app.SampleWindowSlider.MajorTicks + "%"; % "%" to the windowslide bar, looks better
            addpath("C:\Users\peter\Desktop\Honours Project\Simulation"); % if the audio files are in different libraries
            app.LowerWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(1) / 100) + 1;
            app.UpperWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(2) / 100);
        end

        % Callback function: BaseSignalListBox, BaseSignalListBox
        function load_signal(app, event)
            [app.Sig, app.Fs] = audioread(app.BaseSignalListBox.Value); % according to the list value, which is a file name for easy use
            [app.Sig_rows,app.Sig_cols] = size(app.Sig);  % checking the size of the signal
            app.SampleRateValueLabel.Text = num2str(app.Fs) + " Hertz"; % prints out the sampling frequency
            app.SampleSizeValueLabel.Text = num2str(app.Sig_rows) + " x " + num2str(app.Sig_cols) + " x " + class(app.Sig); % prints out the size of the signal
            app.SampleRateValueLabel.Visible = "on"; % turning the labels visible
            app.SampleSizeValueLabel.Visible = "on";
            switch app.Sig_cols         % according to the cols, determines the channel number and make the push buttons visible, need more work on this
                case 1
                    app.Ch1Button.Visible = "off";
                    app.Ch2Button.Visible = "off";
                    app.Ch3Button.Visible = "off";
                case 2
                    app.Ch1Button.Visible = "on";
                    app.Ch2Button.Visible = "on";
                    app.Ch3Button.Visible = "off";
                case 3
                    app.Ch1Button.Visible = "on";
                    app.Ch2Button.Visible = "on";
                    app.Ch3Button.Visible = "on";
                otherwise
                    app.Ch1Button.Visible = "off";
                    app.Ch2Button.Visible = "off";
                    app.Ch3Button.Visible = "off";

            end
            plot(app.UIAxes,app.Sig);  % plot the original signal and clears the other two
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.LowerWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(1) / 100) + 1; 
            app.UpperWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(2) / 100);
            xline(app.UIAxes, app.LowerWindowLimit); % needs the line according to the values
            xline(app.UIAxes, app.UpperWindowLimit);

        end

        % Callback function
        function select_signal(app, event)
 
        end

        % Double-clicked callback: BaseSignalListBox
        function play_signal(app, event)
            sound(app.Sig, app.Fs);
        end

        % Value changed function: DelayUnitSpinner
        function DelayUnitSpinnerValueChanged(app, event)
            
        end

        % Callback function: StartButton, UIAxes3
        function Correlation(app, event)
            cla(app.UIAxes3,"reset"); % clears the previous plot at axes3
            cor=xcorr(app.Sig,app.Mod_Sig); % correlations calculations
            plot(app.UIAxes3,cor); % plot the correlations result, need more work on this, where the max, labels, more visualization, etc...
        end

        % Value changed function: SampleWindowSlider
        function regenerate(app, event)
            % This caused the problem with the window function
            % app.LowerWindowLimit = value(1);
            % app.UpperWindowLimit = value(2);
            app.LowerWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(1) / 100) + 1;
            app.UpperWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(2) / 100);
        end

        % Value changing function: SampleWindowSlider
        function movingline(app, event)
            app.LowerWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(1) / 100) + 1; 
            app.UpperWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(2) / 100);
            plot(app.UIAxes,app.Sig);
            xline(app.UIAxes, app.LowerWindowLimit);
            xline(app.UIAxes, app.UpperWindowLimit);
            windowsizetext = "Window Index: " + num2str(app.LowerWindowLimit) + " - " + num2str(app.UpperWindowLimit) + " " + class(app.Mod_Sig);
            app.WindowIdxLabel.Text = windowsizetext;
            app.WindowIdxLabel.Visible = "on";


        end

        % Button pushed function: RePlotButton
        function ModSigPlot(app, event)
            app.LowerWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(1) / 100) + 1; %need to recalculate the limits to the actual base signal
            app.UpperWindowLimit = round(app.Sig_rows * app.SampleWindowSlider.Value(2) / 100);
            z = zeros(abs(app.DelayUnitSpinner.Value),app.Sig_cols);
            [zrows,zcols] = size(z);
            if (app.DelayUnitSpinner.Value>0)
                delayedsig = app.Sig(1:end-zrows,zcols);
                app.Mod_Sig = [z; delayedsig];
            elseif (app.DelayUnitSpinner.Value<0)
                advancesig = app.Sig(1:end-zrows-1,zcols);
                app.Mod_Sig = [advancesig,z];
            else
                app.Mod_Sig = app.Sig;
            end
            app.StartButton.Visible = "on";%Start button become Visible
            [mrows,mcols] = size(app.Mod_Sig);
            sizetext = "Mod. Sig. Size: " + num2str(mrows) + " x " + num2str(mcols) + " x " + class(app.Mod_Sig);
            app.ModSigSizeLabel.Text = sizetext;
            app.ModSigSizeLabel.Visible = "on";
            windowsizetext = "Window Size: " + num2str(app.LowerWindowLimit) + " - " + num2str(app.UpperWindowLimit) + " " + class(app.Mod_Sig);
            app.WindowIdxLabel.Text = windowsizetext;
            app.WindowIdxLabel.Visible = "on";
            
            plot(app.UIAxes2,(app.LowerWindowLimit:app.UpperWindowLimit),app.Mod_Sig(app.LowerWindowLimit:app.UpperWindowLimit,:));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool2 = uitoggletool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Cross-Correlation')
            xlabel(app.UIAxes3, 'Samples')
            ylabel(app.UIAxes3, 'Correlation Factor')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @Correlation, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Modulated Signal')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Signal')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.CrossCorrelatorLabel = uilabel(app.GridLayout);
            app.CrossCorrelatorLabel.Interpreter = 'latex';
            app.CrossCorrelatorLabel.Text = 'Cross-Correlator';
            app.BaseSignalLabel = uilabel(app.GridLayout);
            app.BaseSignalLabel.Text = 'Base Signal';
            app.BaseSignalListBox = uilistbox(app.GridLayout);
            app.BaseSignalListBox.Items = {'Bird_Song.wav', 'Cuckoo.wav', 'Chirp.wav', 'FreqSweep.wav', 'Speech.wav', 'SpeechEnvelop.wav', 'Custom.wav'};
            app.BaseSignalListBox.ValueChangedFcn = createCallbackFcn(app, @load_signal, true);
            app.BaseSignalListBox.ClickedFcn = createCallbackFcn(app, @load_signal, true);
            app.BaseSignalListBox.DoubleClickedFcn = createCallbackFcn(app, @play_signal, true);
            app.BaseSignalListBox.Value = 'Bird_Song.wav';
            app.DelayUnitSpinnerLabel = uilabel(app.GridLayout);
            app.DelayUnitSpinnerLabel.Text = 'Delay (Unit)';
            app.DelayUnitSpinner = uispinner(app.GridLayout);
            app.DelayUnitSpinner.Limits = [0 30000];
            app.DelayUnitSpinner.ValueChangedFcn = createCallbackFcn(app, @DelayUnitSpinnerValueChanged, true);
            app.SampleWindowSliderLabel = uilabel(app.GridLayout);
            app.SampleWindowSliderLabel.Text = 'Sample Window';
            app.SampleWindowSlider = uislider(app.GridLayout, 'range');
            app.SampleWindowSlider.ValueChangedFcn = createCallbackFcn(app, @regenerate, true);
            app.SampleWindowSlider.ValueChangingFcn = createCallbackFcn(app, @movingline, true);
            app.NoisedBSpinnerLabel = uilabel(app.GridLayout);
            app.NoisedBSpinnerLabel.Text = 'Noise (dB)';
            app.NoisedBSpinner = uispinner(app.GridLayout);
            app.NoisedBSpinner.Limits = [0 100];
            app.LossdBSpinnerLabel = uilabel(app.GridLayout);
            app.LossdBSpinnerLabel.Text = 'Loss (dB)';
            app.LossdBSpinner = uispinner(app.GridLayout);
            app.LossdBSpinner.Limits = [0 100];
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Correlation, true);
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Start';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.Ch1Button = uiradiobutton(app.ButtonGroup);
            app.Ch1Button.Visible = 'off';
            app.Ch1Button.Text = 'Ch1';
            app.Ch1Button.Value = true;
            app.Ch2Button = uiradiobutton(app.ButtonGroup);
            app.Ch2Button.Visible = 'off';
            app.Ch2Button.Text = 'Ch2';
            app.Ch3Button = uiradiobutton(app.ButtonGroup);
            app.Ch3Button.Visible = 'off';
            app.Ch3Button.Text = 'Ch3';
            app.SampleRateLabel = uilabel(app.GridLayout);
            app.SampleRateLabel.Text = 'Sample Rate:';
            app.SampleSizeLabel = uilabel(app.GridLayout);
            app.SampleSizeLabel.Text = 'Sample Size:';
            app.SampleRateValueLabel = uilabel(app.GridLayout);
            app.SampleRateValueLabel.Visible = 'off';
            app.SampleRateValueLabel.Text = 'Sample Rate Value';
            app.SampleSizeValueLabel = uilabel(app.GridLayout);
            app.SampleSizeValueLabel.Visible = 'off';
            app.SampleSizeValueLabel.Text = 'Sample Size Value';
            app.ModSigSizeLabel = uilabel(app.GridLayout);
            app.ModSigSizeLabel.Text = 'Mod. Sig. Size:';
            app.RePlotButton = uibutton(app.GridLayout, 'push');
            app.RePlotButton.ButtonPushedFcn = createCallbackFcn(app, @ModSigPlot, true);
            app.RePlotButton.Text = 'Re-Plot';
            app.WindowIdxLabel = uilabel(app.GridLayout);
            app.WindowIdxLabel.Text = 'Window Index:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crosscorrelation_PS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end